classdef NCOM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NCOMUIFigure        matlab.ui.Figure
        PasarButton         matlab.ui.control.Button
        N1EditFieldLabel    matlab.ui.control.Label
        N1EditField         matlab.ui.control.EditField
        N1EditField_2Label  matlab.ui.control.Label
        N1EditField_2       matlab.ui.control.EditField
        Label               matlab.ui.control.Label
        Label_2             matlab.ui.control.Label
    end


    methods (Access = private)
    
        function results = func(app)
            
        end
        
    end


    methods (Access = private)

        % Button pushed function: PasarButton
        function PasarButtonPushed(app, event)
            a=app.N1EditField.Value;
             len=strlength(a);


if (a(1)=='(' && a(len)==')' && count(a,",")==1 && count(a,"(")==1 && count(a,")")==1)
        binomica=erase(a,"(");
        binomica=erase(binomica,")");
        binomica_separado=strsplit(binomica,",");
        [real,realer]=str2num(binomica_separado{1});
        [img,imger]=str2num(binomica_separado{2});
        if(imger==1&&realer==1)
             [mod,ang]=  bin2pol(real,img)

                                  app.Label.Text='Binomica';
            app.Label_2.Text='Polar';
            app.N1EditField_2.Value=char(strcat("[",num2str(mod),";",num2str(ang),"]"));
                       
        else
            app.N1EditField_2.Value='ERROR DATO, Vuelve a ingresarlo';
        end

        
elseif (a(1)=='[' && a(len)==']' && count(a,";")==1 && count(a,"[")==1 && count(a,"]")==1)

        polar=erase(a,"[");
        polar=erase(polar,"]");
        polar_separado=strsplit(polar,";");
        [mod,moder]=str2num(polar_separado{1});
        [ang,anger]=str2num(polar_separado{2});
        if(moder==1&&anger==1 && mod>=0)
          [real,img]=pol2bin(mod,ang)
                       app.Label.Text='Polar';
                       app.Label_2.Text='Binomica';
          app.N1EditField_2.Value=char(strcat("(",num2str(real),",",num2str(img),")"));
            
        else
            app.N1EditField_2.Value='ERROR DATO, Vuelve a ingresarlo';
        end

    else
            app.N1EditField_2.Value='ERROR DATO, Vuelve a ingresarlo';
end


function [ mod,ang ] = bin2pol( real,img )
%UNTITLED Summary of this function goes here
%   Detailed explanation goes here
    mod=sqrt(real^2+img^2);
    ang_=atan(img/real);
    if(real>0 && img>0)
       ang=ang_;
    elseif(real>0 && img<0)
       ang=ang_+2*pi;
    else
       ang=ang_+pi;
    end
    

end


function [ real,img,err ] = pol2bin( mod,ang )
%UNTITLED Summary of this function goes here
%   Detailed explanation goes here
    if (mod>=0)
        real=mod*cos(ang);
        img=mod*sin(ang);
        err=1;
    else
        err=0;
    end

end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NCOMUIFigure = uifigure;
            app.NCOMUIFigure.Name = 'NCOM';
            app.PasarButton = uibutton(app.NCOMUIFigure, 'push');
            app.PasarButton.ButtonPushedFcn = createCallbackFcn(app, @PasarButtonPushed, true);
            app.PasarButton.Text = 'Pasar';
            app.N1EditFieldLabel = uilabel(app.NCOMUIFigure);
            app.N1EditFieldLabel.Text = 'N1';
            app.N1EditField = uieditfield(app.NCOMUIFigure, 'text');
            app.N1EditField_2Label = uilabel(app.NCOMUIFigure);
            app.N1EditField_2Label.Text = 'N1';
            app.N1EditField_2 = uieditfield(app.NCOMUIFigure, 'text');
            app.N1EditField_2.Editable = 'off';
            app.Label = uilabel(app.NCOMUIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.NCOMUIFigure);
            app.Label_2.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = NCOM

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NCOMUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NCOMUIFigure)
        end
    end
end